classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Assignment2PredictionandDetectionofEpilecticSeizuresLabel  matlab.ui.control.Label
        RenatoMatos2015257602SrgioMachado2017265620Label  matlab.ui.control.Label
        TestanetalreadytrainedPanel    matlab.ui.container.Panel
        PatientDropDownLabel           matlab.ui.control.Label
        PatientDropDown                matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        ForDropDownLabel               matlab.ui.control.Label
        ForDropDown                    matlab.ui.control.DropDown
        TestButton                     matlab.ui.control.Button
        SensitivityLabel               matlab.ui.control.Label
        SpecificityLabel               matlab.ui.control.Label
        SEpLabel                       matlab.ui.control.Label
        SPpLabel                       matlab.ui.control.Label
        PointbyPointLabel              matlab.ui.control.Label
        SensitivityLabel_4             matlab.ui.control.Label
        SpecificityLabel_4             matlab.ui.control.Label
        SEsLabel                       matlab.ui.control.Label
        SPsLabel                       matlab.ui.control.Label
        SeizurebySeizureLabel          matlab.ui.control.Label
        TrainanewnetPanel              matlab.ui.container.Panel
        PatientDropDown_2Label         matlab.ui.control.Label
        PatientDropDown_2              matlab.ui.control.DropDown
        TypeDropDown_2Label            matlab.ui.control.Label
        TypeDropDown_2                 matlab.ui.control.DropDown
        AutoencoderDropDownLabel       matlab.ui.control.Label
        AutoencoderDropDown            matlab.ui.control.DropDown
        DataLabel                      matlab.ui.control.Label
        ClassbalancingDropDownLabel    matlab.ui.control.Label
        ClassbalancingDropDown         matlab.ui.control.DropDown
        ValidationDropDownLabel        matlab.ui.control.Label
        ValidationDropDown             matlab.ui.control.DropDown
        ShallowNetLabel                matlab.ui.control.Label
        TransferfunctionDropDownLabel  matlab.ui.control.Label
        TransferfunctionDropDown       matlab.ui.control.DropDown
        TrainingfunctionDropDownLabel  matlab.ui.control.Label
        TrainingfunctionDropDown       matlab.ui.control.DropDown
        DeepNetLabel                   matlab.ui.control.Label
        TypeDropDown_3Label            matlab.ui.control.Label
        TypeDropDown_3                 matlab.ui.control.DropDown
        SolverDropDownLabel            matlab.ui.control.Label
        SolverDropDown                 matlab.ui.control.DropDown
        NeuronsLabel                   matlab.ui.control.Label
        NeuronsEditField               matlab.ui.control.EditField
        HiddenLayersLabel              matlab.ui.control.Label
        HiddenLayersEditField          matlab.ui.control.EditField
        NeuronsLSTMLabel               matlab.ui.control.Label
        NeuronsLSTMEditField           matlab.ui.control.EditField
        LayersLSTMDropDownLabel        matlab.ui.control.Label
        LayersLSTMDropDown             matlab.ui.control.DropDown
        TrainShallowButton             matlab.ui.control.Button
        TrainDeepButton                matlab.ui.control.Button
        DetectionPbyPLabel             matlab.ui.control.Label
        ErrorWeightsDropDownLabel      matlab.ui.control.Label
        ErrorWeightsDropDown           matlab.ui.control.DropDown
        SensitivityLabel_5             matlab.ui.control.Label
        SpecificityLabel_5             matlab.ui.control.Label
        DSEpLabel                      matlab.ui.control.Label
        DSPpLabel                      matlab.ui.control.Label
        SensitivityLabel_6             matlab.ui.control.Label
        SpecificityLabel_6             matlab.ui.control.Label
        DSEsLabel                      matlab.ui.control.Label
        DSPsLabel                      matlab.ui.control.Label
        DetectionSbySLabel             matlab.ui.control.Label
        PredicitonPbyPLabel            matlab.ui.control.Label
        SensitivityLabel_7             matlab.ui.control.Label
        SpecificityLabel_7             matlab.ui.control.Label
        PSEpLabel                      matlab.ui.control.Label
        PSPpLabel                      matlab.ui.control.Label
        SensitivityLabel_8             matlab.ui.control.Label
        SpecificityLabel_8             matlab.ui.control.Label
        PSEsLabel                      matlab.ui.control.Label
        PSPsLabel                      matlab.ui.control.Label
        PredictionSbySLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            [SEp,SPp,SEs,SPs] = mainTest(app.PatientDropDown.Value, app.TypeDropDown.Value, app.ForDropDown.Value);
            app.SEpLabel.Text = sprintf('%.2f',SEp);
            app.SPpLabel.Text = sprintf('%.2f',SPp);
            app.SEsLabel.Text = sprintf('%.2f',SEs);
            app.SPsLabel.Text = sprintf('%.2f',SPs);
        end

        % Button pushed function: TrainShallowButton
        function TrainShallowButtonPushed(app, event)
            [DSEp,DSPp,PSEp,PSPp, DSEs,DSPs,PSEs,PSPs] = mainTrainShallow(app.PatientDropDown_2.Value, app.AutoencoderDropDown.Value, app.ClassbalancingDropDown.Value, app.ValidationDropDown.Value, app.TypeDropDown_2.Value, app.TransferfunctionDropDown.Value, app.TrainingfunctionDropDown.Value, app.NeuronsEditField.Value, app.HiddenLayersEditField.Value, app.ErrorWeightsDropDown.Value);
            app.DSEpLabel.Text = sprintf('%.2f',DSEp);
            app.DSPpLabel.Text = sprintf('%.2f',DSPp);
            app.PSEpLabel.Text = sprintf('%.2f',PSEp);
            app.PSPpLabel.Text = sprintf('%.2f',PSPp);
            app.DSEsLabel.Text = sprintf('%.2f',DSEs);
            app.DSPsLabel.Text = sprintf('%.2f',DSPs);
            app.PSEsLabel.Text = sprintf('%.2f',PSEs);
            app.PSPsLabel.Text = sprintf('%.2f',PSPs);
        end

        % Button pushed function: TrainDeepButton
        function TrainDeepButtonPushed(app, event)
            [DSEp,DSPp,PSEp,PSPp, DSEs,DSPs,PSEs,PSPs] = mainTrainDeep(app.PatientDropDown_2.Value, app.AutoencoderDropDown.Value, app.ClassbalancingDropDown.Value, app.ValidationDropDown.Value, app.TypeDropDown_3.Value, app.SolverDropDown.Value, app.LayersLSTMDropDown.Value, app.NeuronsLSTMEditField.Value);
            app.DSEpLabel.Text = sprintf('%.2f',DSEp);
            app.DSPpLabel.Text = sprintf('%.2f',DSPp);
            app.PSEpLabel.Text = sprintf('%.2f',PSEp);
            app.PSPpLabel.Text = sprintf('%.2f',PSPp);
            app.DSEsLabel.Text = sprintf('%.2f',DSEs);
            app.DSPsLabel.Text = sprintf('%.2f',DSPs);
            app.PSEsLabel.Text = sprintf('%.2f',PSEs);
            app.PSPsLabel.Text = sprintf('%.2f',PSPs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Assignment2PredictionandDetectionofEpilecticSeizuresLabel = uilabel(app.UIFigure);
            app.Assignment2PredictionandDetectionofEpilecticSeizuresLabel.Text = 'Assignment 2: Prediction and Detection of Epilectic Seizures';
            app.RenatoMatos2015257602SrgioMachado2017265620Label = uilabel(app.UIFigure);
            app.RenatoMatos2015257602SrgioMachado2017265620Label.Text = 'Renato Matos (2015257602), Sérgio Machado (2017265620)';
            app.TestanetalreadytrainedPanel = uipanel(app.UIFigure);
            app.TestanetalreadytrainedPanel.Title = 'Test a net already trained';
            app.PatientDropDownLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.PatientDropDownLabel.Text = 'Patient:';
            app.PatientDropDown = uidropdown(app.TestanetalreadytrainedPanel);
            app.PatientDropDown.Items = {'54802', '112502'};
            app.PatientDropDown.Value = '54802';
            app.TypeDropDownLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.TestanetalreadytrainedPanel);
            app.TypeDropDown.Items = {'Feedforward', 'Layer Recurrent', 'CNN', 'LSTM'};
            app.TypeDropDown.Value = 'Feedforward';
            app.ForDropDownLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.ForDropDownLabel.Text = 'For:';
            app.ForDropDown = uidropdown(app.TestanetalreadytrainedPanel);
            app.ForDropDown.Items = {'Detect', 'Prevent'};
            app.ForDropDown.Value = 'Detect';
            app.TestButton = uibutton(app.TestanetalreadytrainedPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.SensitivityLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.SensitivityLabel.Text = 'Sensitivity:';
            app.SpecificityLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.SpecificityLabel.Text = 'Specificity:';
            app.SEpLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.SEpLabel.Text = '-----';
            app.SPpLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.SPpLabel.Text = '-----';
            app.PointbyPointLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.PointbyPointLabel.Text = 'Point by Point';
            app.SensitivityLabel_4 = uilabel(app.TestanetalreadytrainedPanel);
            app.SensitivityLabel_4.Text = 'Sensitivity:';
            app.SpecificityLabel_4 = uilabel(app.TestanetalreadytrainedPanel);
            app.SpecificityLabel_4.Text = 'Specificity:';
            app.SEsLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.SEsLabel.Text = '-----';
            app.SPsLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.SPsLabel.Text = '-----';
            app.SeizurebySeizureLabel = uilabel(app.TestanetalreadytrainedPanel);
            app.SeizurebySeizureLabel.Text = 'Seizure by Seizure';
            app.TrainanewnetPanel = uipanel(app.UIFigure);
            app.TrainanewnetPanel.Title = 'Train a new net';
            app.PatientDropDown_2Label = uilabel(app.TrainanewnetPanel);
            app.PatientDropDown_2Label.Text = 'Patient:';
            app.PatientDropDown_2 = uidropdown(app.TrainanewnetPanel);
            app.PatientDropDown_2.Items = {'54802', '112502'};
            app.PatientDropDown_2.Value = '54802';
            app.TypeDropDown_2Label = uilabel(app.TrainanewnetPanel);
            app.TypeDropDown_2Label.Text = 'Type:';
            app.TypeDropDown_2 = uidropdown(app.TrainanewnetPanel);
            app.TypeDropDown_2.Items = {'Feedforward', 'Layer Recurrent'};
            app.TypeDropDown_2.Value = 'Feedforward';
            app.AutoencoderDropDownLabel = uilabel(app.TrainanewnetPanel);
            app.AutoencoderDropDownLabel.Text = 'Autoencoder:';
            app.AutoencoderDropDown = uidropdown(app.TrainanewnetPanel);
            app.AutoencoderDropDown.Items = {'None', '3 features', '10 features'};
            app.AutoencoderDropDown.Value = 'None';
            app.DataLabel = uilabel(app.TrainanewnetPanel);
            app.DataLabel.Text = 'Data';
            app.ClassbalancingDropDownLabel = uilabel(app.TrainanewnetPanel);
            app.ClassbalancingDropDownLabel.Text = 'Class balancing:';
            app.ClassbalancingDropDown = uidropdown(app.TrainanewnetPanel);
            app.ClassbalancingDropDown.Items = {'Yes', 'No'};
            app.ClassbalancingDropDown.Value = 'Yes';
            app.ValidationDropDownLabel = uilabel(app.TrainanewnetPanel);
            app.ValidationDropDownLabel.Text = 'Validation:';
            app.ValidationDropDown = uidropdown(app.TrainanewnetPanel);
            app.ValidationDropDown.Items = {'Yes', 'No'};
            app.ValidationDropDown.Value = 'Yes';
            app.ShallowNetLabel = uilabel(app.TrainanewnetPanel);
            app.ShallowNetLabel.Text = 'Shallow Net';
            app.TransferfunctionDropDownLabel = uilabel(app.TrainanewnetPanel);
            app.TransferfunctionDropDownLabel.Text = 'Transfer function:';
            app.TransferfunctionDropDown = uidropdown(app.TrainanewnetPanel);
            app.TransferfunctionDropDown.Items = {'logsig', 'purelin', 'tansig'};
            app.TransferfunctionDropDown.Value = 'logsig';
            app.TrainingfunctionDropDownLabel = uilabel(app.TrainanewnetPanel);
            app.TrainingfunctionDropDownLabel.Text = 'Training function:';
            app.TrainingfunctionDropDown = uidropdown(app.TrainanewnetPanel);
            app.TrainingfunctionDropDown.Items = {'trainscg', 'trainlm', 'traincgb'};
            app.TrainingfunctionDropDown.Value = 'trainscg';
            app.DeepNetLabel = uilabel(app.TrainanewnetPanel);
            app.DeepNetLabel.Text = 'Deep Net';
            app.TypeDropDown_3Label = uilabel(app.TrainanewnetPanel);
            app.TypeDropDown_3Label.Text = 'Type:';
            app.TypeDropDown_3 = uidropdown(app.TrainanewnetPanel);
            app.TypeDropDown_3.Items = {'CNN', 'LSTM'};
            app.TypeDropDown_3.Value = 'CNN';
            app.SolverDropDownLabel = uilabel(app.TrainanewnetPanel);
            app.SolverDropDownLabel.Text = 'Solver:';
            app.SolverDropDown = uidropdown(app.TrainanewnetPanel);
            app.SolverDropDown.Items = {'sgdm', 'rmsdrop', 'adam'};
            app.SolverDropDown.Value = 'sgdm';
            app.NeuronsLabel = uilabel(app.TrainanewnetPanel);
            app.NeuronsLabel.Text = 'Neurons:';
            app.NeuronsEditField = uieditfield(app.TrainanewnetPanel, 'text');
            app.NeuronsEditField.Value = '10';
            app.HiddenLayersLabel = uilabel(app.TrainanewnetPanel);
            app.HiddenLayersLabel.Text = 'Hidden Layers:';
            app.HiddenLayersEditField = uieditfield(app.TrainanewnetPanel, 'text');
            app.HiddenLayersEditField.Value = '1';
            app.NeuronsLSTMLabel = uilabel(app.TrainanewnetPanel);
            app.NeuronsLSTMLabel.Text = 'Neurons (LSTM):';
            app.NeuronsLSTMEditField = uieditfield(app.TrainanewnetPanel, 'text');
            app.NeuronsLSTMEditField.Value = '100';
            app.LayersLSTMDropDownLabel = uilabel(app.TrainanewnetPanel);
            app.LayersLSTMDropDownLabel.Text = 'Layers (LSTM):';
            app.LayersLSTMDropDown = uidropdown(app.TrainanewnetPanel);
            app.LayersLSTMDropDown.Items = {'2', '3'};
            app.LayersLSTMDropDown.Value = '2';
            app.TrainShallowButton = uibutton(app.TrainanewnetPanel, 'push');
            app.TrainShallowButton.ButtonPushedFcn = createCallbackFcn(app, @TrainShallowButtonPushed, true);
            app.TrainShallowButton.Text = 'Train Shallow';
            app.TrainDeepButton = uibutton(app.TrainanewnetPanel, 'push');
            app.TrainDeepButton.ButtonPushedFcn = createCallbackFcn(app, @TrainDeepButtonPushed, true);
            app.TrainDeepButton.Text = 'Train Deep';
            app.DetectionPbyPLabel = uilabel(app.TrainanewnetPanel);
            app.DetectionPbyPLabel.Text = 'Detection P. by P.';
            app.ErrorWeightsDropDownLabel = uilabel(app.TrainanewnetPanel);
            app.ErrorWeightsDropDownLabel.Text = 'Error Weights:';
            app.ErrorWeightsDropDown = uidropdown(app.TrainanewnetPanel);
            app.ErrorWeightsDropDown.Items = {'Yes', 'No'};
            app.ErrorWeightsDropDown.Value = 'Yes';
            app.SensitivityLabel_5 = uilabel(app.TrainanewnetPanel);
            app.SensitivityLabel_5.Text = 'Sensitivity:';
            app.SpecificityLabel_5 = uilabel(app.TrainanewnetPanel);
            app.SpecificityLabel_5.Text = 'Specificity:';
            app.DSEpLabel = uilabel(app.TrainanewnetPanel);
            app.DSEpLabel.Text = '-----';
            app.DSPpLabel = uilabel(app.TrainanewnetPanel);
            app.DSPpLabel.Text = '-----';
            app.SensitivityLabel_6 = uilabel(app.TrainanewnetPanel);
            app.SensitivityLabel_6.Text = 'Sensitivity:';
            app.SpecificityLabel_6 = uilabel(app.TrainanewnetPanel);
            app.SpecificityLabel_6.Text = 'Specificity:';
            app.DSEsLabel = uilabel(app.TrainanewnetPanel);
            app.DSEsLabel.Text = '-----';
            app.DSPsLabel = uilabel(app.TrainanewnetPanel);
            app.DSPsLabel.Text = '-----';
            app.DetectionSbySLabel = uilabel(app.TrainanewnetPanel);
            app.DetectionSbySLabel.Text = 'Detection S. by S.';
            app.PredicitonPbyPLabel = uilabel(app.TrainanewnetPanel);
            app.PredicitonPbyPLabel.Text = 'Prediciton P. by P.';
            app.SensitivityLabel_7 = uilabel(app.TrainanewnetPanel);
            app.SensitivityLabel_7.Text = 'Sensitivity:';
            app.SpecificityLabel_7 = uilabel(app.TrainanewnetPanel);
            app.SpecificityLabel_7.Text = 'Specificity:';
            app.PSEpLabel = uilabel(app.TrainanewnetPanel);
            app.PSEpLabel.Text = '-----';
            app.PSPpLabel = uilabel(app.TrainanewnetPanel);
            app.PSPpLabel.Text = '-----';
            app.SensitivityLabel_8 = uilabel(app.TrainanewnetPanel);
            app.SensitivityLabel_8.Text = 'Sensitivity:';
            app.SpecificityLabel_8 = uilabel(app.TrainanewnetPanel);
            app.SpecificityLabel_8.Text = 'Specificity:';
            app.PSEsLabel = uilabel(app.TrainanewnetPanel);
            app.PSEsLabel.Text = '-----';
            app.PSPsLabel = uilabel(app.TrainanewnetPanel);
            app.PSPsLabel.Text = '-----';
            app.PredictionSbySLabel = uilabel(app.TrainanewnetPanel);
            app.PredictionSbySLabel.Text = 'Prediction S. by S.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end