classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Assignment1OpticalCharacterRecognitionLabel  matlab.ui.control.Label
        RenatoMatos2015257602Label   matlab.ui.control.Label
        SrgioMachado2017265602Label  matlab.ui.control.Label
        ChoosetheneuralnetworktouseLabel  matlab.ui.control.Label
        classifierLabel              matlab.ui.control.Label
        classifierPurelinTrainbTrainlmButton  matlab.ui.control.Button
        classifierPurelinTrainbTraingdaButton  matlab.ui.control.Button
        classifierPurelinTrainbTrainscgButton  matlab.ui.control.Button
        classifierPerceptronButton   matlab.ui.control.Button
        assocLabel                   matlab.ui.control.Label
        assocPurelinTrainbTrainlmButton  matlab.ui.control.Button
        assocPurelinTrainbTrainscgButton  matlab.ui.control.Button
        assocPurelinTrainbTraingdaButton  matlab.ui.control.Button
        assocPerceptronButton        matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % classifierPurelinTrainbTrainlmButton
        function classifierPurelinTrainbTrainlmButtonPushed(app, event)
            option = 1;
            save("option.mat","option");
            mpaper();
        end

        % Button pushed function: 
        % classifierPurelinTrainbTraingdaButton
        function classifierPurelinTrainbTraingdaButtonPushed(app, event)
            option = 2;
            save("option.mat","option");
            mpaper();
        end

        % Button pushed function: 
        % classifierPurelinTrainbTrainscgButton
        function classifierPurelinTrainbTrainscgButtonPushed(app, event)
            option = 3;
            save("option.mat","option");
            mpaper();
        end

        % Button pushed function: classifierPerceptronButton
        function classifierPerceptronButtonPushed(app, event)
            option = 4;
            save("option.mat","option");
            mpaper();
        end

        % Button pushed function: assocPurelinTrainbTrainlmButton
        function assocPurelinTrainbTrainlmButtonPushed(app, event)
            option = 5;
            save("option.mat","option");
            mpaper();
        end

        % Button pushed function: assocPurelinTrainbTrainscgButton
        function assocPurelinTrainbTrainscgButtonPushed(app, event)
            option = 6;
            save("option.mat","option");
            mpaper();
        end

        % Button pushed function: assocPurelinTrainbTraingdaButton
        function assocPurelinTrainbTraingdaButtonPushed(app, event)
            option = 7;
            save("option.mat","option");
            mpaper();
        end

        % Button pushed function: assocPerceptronButton
        function assocPerceptronButtonPushed(app, event)
            option = 8;
            save("option.mat","option");
            mpaper();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Assignment1OpticalCharacterRecognitionLabel = uilabel(app.UIFigure);
            app.Assignment1OpticalCharacterRecognitionLabel.Text = 'Assignment 1: Optical Character Recognition';
            app.RenatoMatos2015257602Label = uilabel(app.UIFigure);
            app.RenatoMatos2015257602Label.Text = 'Renato Matos, 2015257602';
            app.SrgioMachado2017265602Label = uilabel(app.UIFigure);
            app.SrgioMachado2017265602Label.Text = 'Sérgio Machado, 2017265602';
            app.ChoosetheneuralnetworktouseLabel = uilabel(app.UIFigure);
            app.ChoosetheneuralnetworktouseLabel.Text = 'Choose the neural network to use:';
            app.classifierLabel = uilabel(app.UIFigure);
            app.classifierLabel.Text = 'Classifier (1 layer)';
            app.classifierPurelinTrainbTrainlmButton = uibutton(app.UIFigure, 'push');
            app.classifierPurelinTrainbTrainlmButton.ButtonPushedFcn = createCallbackFcn(app, @classifierPurelinTrainbTrainlmButtonPushed, true);
            app.classifierPurelinTrainbTrainlmButton.Text = 'Purelin, trainb, trainlm';
            app.classifierPurelinTrainbTraingdaButton = uibutton(app.UIFigure, 'push');
            app.classifierPurelinTrainbTraingdaButton.ButtonPushedFcn = createCallbackFcn(app, @classifierPurelinTrainbTraingdaButtonPushed, true);
            app.classifierPurelinTrainbTraingdaButton.Text = 'Purelin, trainb, traingda';
            app.classifierPurelinTrainbTrainscgButton = uibutton(app.UIFigure, 'push');
            app.classifierPurelinTrainbTrainscgButton.ButtonPushedFcn = createCallbackFcn(app, @classifierPurelinTrainbTrainscgButtonPushed, true);
            app.classifierPurelinTrainbTrainscgButton.Text = 'Purelin, trainb, trainscg';
            app.classifierPerceptronButton = uibutton(app.UIFigure, 'push');
            app.classifierPerceptronButton.ButtonPushedFcn = createCallbackFcn(app, @classifierPerceptronButtonPushed, true);
            app.classifierPerceptronButton.Text = 'Perceptron';
            app.assocLabel = uilabel(app.UIFigure);
            app.assocLabel.Text = 'Associative memory + Classifier';
            app.assocPurelinTrainbTrainlmButton = uibutton(app.UIFigure, 'push');
            app.assocPurelinTrainbTrainlmButton.ButtonPushedFcn = createCallbackFcn(app, @assocPurelinTrainbTrainlmButtonPushed, true);
            app.assocPurelinTrainbTrainlmButton.Text = 'Purelin, trainb, trainlm';
            app.assocPurelinTrainbTrainscgButton = uibutton(app.UIFigure, 'push');
            app.assocPurelinTrainbTrainscgButton.ButtonPushedFcn = createCallbackFcn(app, @assocPurelinTrainbTrainscgButtonPushed, true);
            app.assocPurelinTrainbTrainscgButton.Text = 'Purelin, trainb, trainscg';
            app.assocPurelinTrainbTraingdaButton = uibutton(app.UIFigure, 'push');
            app.assocPurelinTrainbTraingdaButton.ButtonPushedFcn = createCallbackFcn(app, @assocPurelinTrainbTraingdaButtonPushed, true);
            app.assocPurelinTrainbTraingdaButton.Text = 'Purelin, trainb, traingda';
            app.assocPerceptronButton = uibutton(app.UIFigure, 'push');
            app.assocPerceptronButton.ButtonPushedFcn = createCallbackFcn(app, @assocPerceptronButtonPushed, true);
            app.assocPerceptronButton.Text = 'Perceptron';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end